classdef FinalProjectWithReachComponent < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        ImportTimetableButton      matlab.ui.control.Button
        ImportandGraphDataButton   matlab.ui.control.Button
        PlayPauseButton            matlab.ui.control.Button
        StopButton                 matlab.ui.control.Button
        RunDefaultTimetableButton  matlab.ui.control.Button
        Label                      matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        ColorKeyTextAreaLabel      matlab.ui.control.Label
        ColorKeyTextArea           matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        im % Description
        play_mode = 1; % 1=play, 2=pause, 3=stop
        Position;      % Position array 
        stopped;       % stopped music
        slow;          % slow music
        fast;          % fast music
        vfast;         % very fast music
        music;         % currently playing music
        last_ii = 0;   % where the counter stopped
    end
    % Academic Integrity Statement:
% We have not used source code obtained from
% any other unauthorized source, either modified
 % or unmodified. Neither have we provided access
 % to our code to other teams. The project we are
 % submitting is our own original work.
    
    methods (Access = private)
        
        function radius = radiusFunc(app) %collaboration effort(everyone pitched in for method)
                        
            %Code To Establish Resolution Values
            resolution = readtable('data/ResolutionData.csv');  % resolution file
            ResolutionData = table2array(resolution);      % ResolutionData
            LatitudeResolutionData1 = ResolutionData((1:6),1); %a points
            LatitudeResolutionData2 = ResolutionData((11:16),1);%c points
            LongitudeResolutionData1 = ResolutionData((6:11),2);% b points
            LongitudeResolutionData2 = ResolutionData((16:21),2);%d points
            for(i = 1:6)
                if(i<6)
                    lat1diff(i) = abs(LatitudeResolutionData1(i)-LatitudeResolutionData1(i+1));
                    lat2diff(i) = abs(LatitudeResolutionData2(i)-LatitudeResolutionData2(i+1));
                    long1diff = abs(LongitudeResolutionData1(i) - LongitudeResolutionData1(i+1));
                    long2diff = abs(LongitudeResolutionData2(i) - LongitudeResolutionData2(i+1));
                end                
            end
            lataverage = (mean(lat1diff) + mean(lat2diff))/2;
            longaverage =(mean(long1diff) + mean(long2diff))/2;
            resolutionscale = mean([lataverage, longaverage]);
            %Set the Radius of Uncertainity Equal to Our Resolution per
            %Meter. Plot all points with given radius as a circle. 
            radius = resolutionscale; % radius to be used to draw
        end
        
        function cmode = getCourse(app,course)
            % course mode - direction being taken (0 = North, 1 = East, 2 = South, 3 = West)
            cmode = 3;                                 % initialize to bearing west
            if (course >= 0 && course < 90)            % if bearing north
                cmode = 0;
            else 
                if (course >= 90 && course < 180 )     % if bearing east
                    cmode = 1;
                else 
                    if (course >= 180 && course < 270) % if bearing south
                        cmode = 2;
                    end
                end
           end 
        end
        
        function doIt(app)
            % Initialize local  variables from class variables
            Position = app.Position; 
            slow = app.slow;
            fast = app.fast;
            vfast = app.vfast;
            music = app.music;
            stopped = app.stopped;
            %t_vfast and t_fast obtained via matlab, took average values of
            %tables corresponding to running and biking
            t_slow = 0.06;                            % threshold for slow walking speed- slowest recordable by matlab
            t_fast = 2.832699409482758;                % threshold for fast speed (running)
            t_vfast = 4.935545226950357;                 % threshold for very fast speed (biking)
            course = Position.course(1);              % set course
            ss = size(Position);                      % get size of positions timetable 
            y = Position(:,1);                        % array of y coord latitude
            x = Position(:,2);                        % array of x coord longitude
            radius = radiusFunc(app);             %  circle for traversing (resolution)
            theta = 0:pi/50:2*pi;                     % angle for circle
            
            % initialize variables to start looping
            cmode = 3;                                % bearing west
            mode = 3;                                 % initialize w/ stopped
            pmode = mode;                             % initialize pmode (previous mode) to mode
            pause_length = 1;                         % set pause/delay to 1
            
            start = 1;                                 % if beginning
            if (app.play_mode == 1 && app.last_ii > 0) % check to see if it was paused before
                disp("Resuming: " + app.play_mode +  " " +app. last_ii)
               start = app.last_ii;                    % if paused, use last/saved counter
               play(music);                            % resume playing currently/last running music
            end
            for ii = start:ss(1)
                app.last_ii = ii;                               % set last/saved counter, JIC of a pause
                if (app.play_mode == 2)                         % if paused
                    disp("Play/pause: " + app.play_mode);
                    pause(music);                               % pause music
                    break;                                      % break out
                else
                    if (app.play_mode == 1)                     % if play chosen (from either a paused/stopped)
                        play (music);                           % play music
                    else
                        if (app.play_mode == 3)                 % if stopped
                            disp("Stopping " + app.play_mode + " " + app.last_ii); 
                            app.last_ii = 0;                    % reset
                            stop (music);                       % stop music
                            break;                              % break out
                        end
                    end
                end
                % moving average/mean
                limit = ii+10;                                  % pick next 10 elements
                if (ii+10 > ss(1))                              % if end of array is reached
                    limit = ss(1);                              % pick whatever is left
                end
                remainder = Position(ii:limit,:);               % get the next 10 (lef over) elements
                rem_speed = remainder.speed;                    % get array
                avg_speed = mean(rem_speed);                    % get mean
                % mode 0 = slow, 1 = fast, 2 = very fast, 3 = stopped
                mode = 3;                                       % initialize to stopped
                if (avg_speed >= t_vfast)                       % if exceeding threshold for v fast
                    mode = 2;
                else 
                    if (avg_speed >= t_fast)                    % if exceeding threshold for fast
                        mode = 1;
                    else
                        if (avg_speed >= t_slow)                % if exceeding threshold for slow 
                            mode = 0;
                        end
                    end
                end
                % pmode - previous mode, if not equal to current mode, then
                % determine the new mode based on speed
                updateL = false; %initialize updateL.
                if (pmode ~= mode)                              % if speed has changed          
                    stop(music);                                % stop running music
                    %disp(pmode + " " + mode);
                    switch (mode)                               % based on new speed/mode set music
                        case 0
                            music = slow;                       % to slow
                            pause_length = 1;                   % set delay to slowest
                        case 1 
                            music = fast;                       % to fast
                            pause_length = 0.6;                 % slightly smaller delay
                        case 2 
                            music = vfast;                      % to v fast
                            pause_length = 0.3;                 % even smaller delay
                        case 3
                            music = stopped;
                            pause_length = 1.5;
                    end  
                    pmode = mode;                               % update previous mode to new mode
                    disp(ii + " " + mode + " " + avg_speed);
                    app.music = music;                          % store in class variable also (jic paused)
                    updateL = true;
                end
                newcmode = getCourse(app, course);                            % get new course
                if (cmode ~= newcmode)
                    cmode = newcmode;
                    updateL = true;
                end
                if (updateL)
                    updateLabel(app, cmode, course, avg_speed);
                end
                xcoordinatelong = radius * cos(theta) + x.longitude(ii);   % x coord
                ycoordinatelat = radius * sin(theta) + y.latitude(ii);     % y coord
                color = 'blue';                                            % initialize color to west
                switch (cmode)
                    case 0
                        color = 'red';                                     % north
                    case 1
                        color = 'green';                                   % east
                    case 2
                        color = 'yellow';                                  % south
                end
                plot(app.UIAxes,xcoordinatelong, ycoordinatelat, color);   % plot  circle
                fill(app.UIAxes, xcoordinatelong, ycoordinatelat, color);  % fill circle
                if (music.isplaying == false)                              % if music is over or stopped
                    play(music);                                           % resume playing
                end
                pause (pause_length);                                      % pause/delay to let music play based on speed
                % disp(cmode + " " + course + " " + color);
                course=Position.course(ii);                                % update direction/course for next iteration of loop
            end            
        end %end of function that parses through the Position data
        
        function updateLabel(app,cmode,course, avg_speed)
            direction = "West"
            switch (cmode)
                case 1
                    direction = "North";
                case 2
                    direction = "East"
                case 3
                    direction = "South"                                    
            end
            app.Label_2.Text = "Direction (course): " + direction + " (" + course + ") ";
            app.Label.Text =  "Avg speed: " + avg_speed;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           %Set Up Graph With The Campus Image File
           [filename]= 'data/CampusMap.png';            
           currentFile = mfilename( 'fullpath' )        
           fileName = mfilename('path')
           fullPath =erase(currentFile,fileName)
           fullname = [fullPath, filename];
           app.im = flipud(imread(fullname));
           image(app.UIAxes, ...
                 'XData',[-121.768286,-121.740476], ...
                 'YData',[38.526083,38.546516], ...
                 'CData',app.im);
           % draw graph axes, title, labels
           hold(app.UIAxes,'on');
           axis(app.UIAxes,'tight');
           axis(app.UIAxes,'equal');
           title(app.UIAxes,'Map of UC Davis')
           xlabel(app.UIAxes, 'Longitude')
           ylabel(app.UIAxes, 'Latitude')
           % initialize class variables, read sound files
           app.last_ii = 0;
           [sty,stfs] = audioread("data/Stopped.wav");
           app.stopped = audioplayer(sty,stfs);
           [sy,sfs] = audioread("data/Walking.wav");
           app.slow = audioplayer(sy,sfs);
           [fy,ffs] = audioread("data/Running.wav");
           app.fast = audioplayer(fy,ffs);
           [vfy,vffs] = audioread("data/Biking.wav");
           app.vfast = audioplayer(vfy,vffs);
           % initialize to stopped music
           app.music = app.stopped;
            
  % Academic Integrity Statement:
% We have not used source code obtained from
% any other unauthorized source, either modified
 % or unmodified. Neither have we provided access
 % to our code to other teams. The project we are
 % submitting is our own original work.


        end

        % Button pushed function: ImportandGraphDataButton
        function ImportandGraphDataButtonPushed(app, event)
            %Code To Import CSV file Data
            disp("Calibrate data")
            [filename,filepath] = uigetfile({'*.csv'});
            importedFile = [filepath, filename];
            readyforOperation = importdata(importedFile);
            y = readyforOperation.data(:,1);
            x = readyforOperation.data(:,2);
            radius = radiusFunc(app)
             for(i = 1:10)
                 % adapted from <https://www.mathworks.com/matlabcentral/answers/98665-how-do-i-plot-a-circle-with-a-given-radius-and-center>
                 % The site gave a general method on how to plot circles in
                 % matlab figures. In this code, we applied the generic
                 % method to draw circles to the graph in our UI and used
                 % it to plot our points with accurate resolution values. 
                 theta = 0:pi/50:2*pi;
                 xcoordinatelong = radius * cos(theta) + x(i); 
                 ycoordinatelat = radius * sin(theta) + y(i);
                 plot(app.UIAxes,xcoordinatelong, ycoordinatelat, 'black');
                 fill(app.UIAxes, xcoordinatelong, ycoordinatelat,'red');
             end


    % Academic Integrity Statement:
% We have not used source code obtained from
% any other unauthorized source, either modified
 % or unmodified. Neither have we provided access
 % to our code to other teams. The project we are
 % submitting is our own original work.
        end

        % Button pushed function: ImportTimetableButton
        function ImportTimetableButtonPushed(app, event)
            disp("Start testing speed");
            [filename,filepath] = uigetfile({'*'});
            importedFile = [filepath, filename];
            data = importdata(importedFile);         % read data from file
            app.Position = data.Position;            % initialize Position variable
            doIt(app);                               % run the main function 
            % Academic Integrity Statement:
% We have not used source code obtained from
% any other unauthorized source, either modified
 % or unmodified. Neither have we provided access
 % to our code to other teams. The project we are
 % submitting is our own original work.
            
        end

        % Button pushed function: PlayPauseButton
        function uponPlayPause(app, event)
            disp("uponPlayPause " + app.play_mode + " " + app.last_ii)
            if (app.play_mode == 1)                               % if playing, pause
                app.play_mode = 2;                                      %doIt is now paused
            else
                if (app.play_mode == 2 || app.play_mode == 3)     % if previously paused or stopped
                    app.play_mode = 1;
                    doIt(app);                                    % resume playing and run main func
                end
            end
        end

        % Button pushed function: StopButton
        function uponStop(app, event)
            clx = app.UIAxes
            app.play_mode = 3;                                     % stop
            disp("Stop " + app.play_mode + " " + app.last_ii);
            cla(clx); %found in documentation
            startupFcn(app);
            % Academic Integrity Statement:
% We have not used source code obtained from
% any other unauthorized source, either modified
 % or unmodified. Neither have we provided access
 % to our code to other teams. The project we are
 % submitting is our own original work.
        end

        % Button pushed function: RunDefaultTimetableButton
        function uponRunTest(app, event)
            disp("Start testing speed automatically");
            data = load("data/AutoPath.mat")           % read file w/ data
            app.Position = data.Position;                % initialize/set Position var
            doIt(app);                                   % run program
            % Academic Integrity Statement:
% We have not used source code obtained from
% any other unauthorized source, either modified
 % or unmodified. Neither have we provided access
 % to our code to other teams. The project we are
 % submitting is our own original work.
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ImportTimetableButton = uibutton(app.UIFigure, 'push');
            app.ImportTimetableButton.ButtonPushedFcn = createCallbackFcn(app, @ImportTimetableButtonPushed, true);
            app.ImportTimetableButton.Text = 'Import Timetable';
            app.ImportandGraphDataButton = uibutton(app.UIFigure, 'push');
            app.ImportandGraphDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportandGraphDataButtonPushed, true);
            app.ImportandGraphDataButton.Text = 'Import and Graph Data';
            app.PlayPauseButton = uibutton(app.UIFigure, 'push');
            app.PlayPauseButton.ButtonPushedFcn = createCallbackFcn(app, @uponPlayPause, true);
            app.PlayPauseButton.Text = 'Play/Pause';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @uponStop, true);
            app.StopButton.Text = 'Stop';
            app.RunDefaultTimetableButton = uibutton(app.UIFigure, 'push');
            app.RunDefaultTimetableButton.ButtonPushedFcn = createCallbackFcn(app, @uponRunTest, true);
            app.RunDefaultTimetableButton.Text = 'Run Default Timetable';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.ColorKeyTextAreaLabel = uilabel(app.UIFigure);
            app.ColorKeyTextAreaLabel.Text = 'Color Key:';
            app.ColorKeyTextArea = uitextarea(app.UIFigure);
            app.ColorKeyTextArea.Value = {'Red = North'; 'Green = East'; 'Yellow = South'; 'Blue = West'; ''; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalProjectWithReachComponent

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end